classdef IVPRO_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IVPRO                    matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        SerialConfigTab          matlab.ui.container.Tab
        CmdBox                   matlab.ui.control.EditField
        CmdBoxLabel              matlab.ui.control.Label
        HistoryBox               matlab.ui.control.TextArea
        HistoryBoxLabel          matlab.ui.control.Label
        Panel_2                  matlab.ui.container.Panel
        DisconnectButton         matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
        RefreshButton            matlab.ui.control.Button
        SelectPortDropDownLabel  matlab.ui.control.Label
        SelectPortDropDown       matlab.ui.control.DropDown
        PreStatusLabel           matlab.ui.control.Label
        StatusLabel              matlab.ui.control.Label
        RXHistory                matlab.ui.control.TextArea
        RXLabel                  matlab.ui.control.Label
        FilterConfigTab          matlab.ui.container.Tab
        SelectImageButton        matlab.ui.control.Button
        FilterControlPanel       matlab.ui.container.ButtonGroup
        DenoiseMeanButton        matlab.ui.control.RadioButton
        SharpenButton            matlab.ui.control.RadioButton
        DenoiseGaussianButton    matlab.ui.control.RadioButton
        EdgeDetectionXButton     matlab.ui.control.RadioButton
        StretchContrastButton    matlab.ui.control.RadioButton
        ThresholdButton          matlab.ui.control.RadioButton
        EdgeDetectionYButton     matlab.ui.control.RadioButton
        DenoiseMedianButton      matlab.ui.control.RadioButton
        ThresholdSpinnerLabel    matlab.ui.control.Label
        ThresholdSpinner         matlab.ui.control.Spinner
        InputImageLabel          matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        RunfilterButton          matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        InputImagePanel          matlab.ui.container.Panel
        InputImagebox            matlab.ui.control.UIAxes
        InputHistogram           matlab.ui.control.UIAxes
        OutputImagePanel         matlab.ui.container.Panel
        OutputImagebox           matlab.ui.control.UIAxes
        OutputHistogram          matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function DisplayRX(app, src, event)
            global SER;
            global OUTPUT_IMG;
            Data = 0;
            for i = 1:2
                for j = 1:2
                    Data = fread(SER,1);
                    OUTPUT_IMG(i,j) = uint8(Data);
                end
            end
            % Generate histogram for input image
            imshow(OUTPUT_IMG, 'Parent', app.OutputImagebox);
            OutputHist = histogram(app.OutputHistogram, OUTPUT_IMG, 'FaceColor',[0 0 0],'EdgeColor', 'none');
            max_lum = max(OutputHist.BinCounts);
            app.OutputHistogram.YLim = [0 max_lum];
            app.OutputHistogram.XTick = (0 : 32 : 255);
            app.OutputHistogram.YTick = (0:max_lum/10:max_lum);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Clean port and RX_PORTial object before starting
            delete(instrfindall);
            % Populate the COM Port
            comPorts = seriallist;
            app.SelectPortDropDown.Items = comPorts;
            app.HistoryBox.Editable = 'off';
            app.StatusLabel.Text = "Disconnected";
        end

        % Key press function: IVPRO
        function SerialTX(app, event)

            
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            global INPUT_IMG;
            
            % Get file location from UI
            [f_name, f_path] = uigetfile({'*.*';'*.bmp';'*.png';'*.jpg';'*.jpeg'}, 'Select image to download to FPGA');
            f_adr = [f_path f_name];
            % Check an image was selected
            if (ischar(f_path))
                % Read image data to input_img var
                INPUT_IMG = imread(f_adr);
                % Update the button text showing the image name
                app.SelectImageButton.Text = f_name;
                % Display image in GUI
                imshow(INPUT_IMG, 'Parent', app.InputImagebox);
                
                % Generate histogram for input image
                input_hist = histogram(app.InputHistogram, INPUT_IMG, 'FaceColor',[0 0 0],'EdgeColor', 'none');
                max_lum = max(input_hist.BinCounts);
                app.InputHistogram.YLim = [0 max_lum];
                app.InputHistogram.YTick = (0:max_lum/10:max_lum);
            end
        end

        % Value changed function: SelectPortDropDown
        function SelectPortDropDownValueChanged(app, event)
           global TRX_PORT;
           TRX_PORT = app.SelectPortDropDown.Value;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global SER;
            global TRX_PORT;
            
            % Close any existing connection
            delete(instrfindall);
            
            % Connect using new parameters
            TRX_PORT = app.SelectPortDropDown.Value;
            SER = serial(TRX_PORT, 'BaudRate', 115200);
            SER.InputBufferSize = 80000;
            SER.BytesAvailableFcnCount = 4;
            SER.BytesAvailableFcnMode = 'byte';
            SER.BytesAvailableFcn = @(src, event) app.DisplayRX(src, event);
            SER.ReadAsyncMode = 'manual';
            SER.StopBits = 1;
            SER.Terminator = 'CR/LF';
            SER.Parity = 'none';
            fopen(SER);            
            if SER.Status == "open"
                app.StatusLabel.Text = "Connected to " + TRX_PORT;
            else
                app.StatusLabel.Text = "Disconnected";
            end
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % Populate the COM Port
            comPorts = seriallist;
            app.SelectPortDropDown.Items = comPorts;
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            global SER;
            fclose(SER);
            delete(instrfindall);
            app.StatusLabel.Text = "Disconnected";
        end

        % Value changed function: CmdBox
        function CmdBoxValueChanged(app, event)
            global TX_DATA;
            global SER;
            
            % Capture uRX_PORT input
            TX_DATA = string(app.CmdBox.Value);
            % Clear the command box
            app.CmdBox.Value = '';
            % Transmit RX_PORTial command
            fprintf(SER, TX_DATA);
            
            % Format the uRX_PORT input into LOG format
            History = strcat('Sent @ ', datestr(now), ': \n', TX_DATA);
            % Update command history log
            app.HistoryBox.Value = sprintf(History);
            
        end

        % Button pushed function: RunfilterButton
        function RunfilterButtonPushed(app, event)
            global SER;
            global INPUT_IMG;
            global OUTPUT_IMG;
            %             PixelCounter = 0;
            PixelValue = 0;
            DL_IMG = rgb2gray(im2uint8(INPUT_IMG));
            
            % imshow(DL_IMG, 'Parent', app.OutputImagebox);
            % Update filter paramters
            F_ADR = 0;
            F_TYPE = 0;
            F_THRESHOLD = 0;
            if app.DenoiseMeanButton.Value == true
                F_TYPE = dec2bin(0,8);
                F_ADR = dec2bin(40,8);
                F_THRESHOLD = dec2bin(0,8);
                
            elseif  app.SharpenButton.Value == true
                F_TYPE = dec2bin(0,8);
                F_ADR = dec2bin(0,8);
                F_THRESHOLD = dec2bin(0,8);
                
            elseif app.DenoiseGaussianButton.Value == true
                F_TYPE = dec2bin(0,8);
                F_ADR = dec2bin(30,8);
                F_THRESHOLD = dec2bin(0,8);
                
            elseif app.EdgeDetectionXButton.Value == true
                F_TYPE = dec2bin(0,8);
                F_ADR = dec2bin(10,8);
                F_THRESHOLD = dec2bin(0,8);
                
            elseif app.StretchContrastButton.Value == true
                F_TYPE = dec2bin(1,8);
                F_ADR = dec2bin(0,8);
                F_THRESHOLD = dec2bin(0,8);
                
            elseif app.ThresholdButton.Value == true
                F_TYPE = dec2bin(2,8);
                F_ADR = dec2bin(0,8);
                F_THRESHOLD = dec2bin(app.ThresholdSpinner.Value, 8);
                
            elseif app.EdgeDetectionYButton.Value == true
                F_TYPE = dec2bin(0,8);
                F_ADR = dec2bin(20,8);
                F_THRESHOLD = dec2bin(0,8);
                
            elseif app.DenoiseMedianButton.Value == true
                F_TYPE = dec2bin(3,8);
                F_ADR = dec2bin(0,8);
                F_THRESHOLD = dec2bin(0,8);
            end
            
            % Filter type command
            fprintf(SER, F_TYPE);
            % Filter address command
            fprintf(SER, F_ADR);
            % Filter threshold command
            fprintf(SER, F_THRESHOLD);
            
            % Transmit image data
            [rows,cols]=size(DL_IMG);
            OUTPUT_IMG = zeros(rows,cols,'uint8');
%             for i=1:rows
%                 for j=1:columns
%                     PixelCounter = PixelCounter + 1;
%                     PixelData = num2str(DL_IMG(i,j));
%                     fprintf(SER, '\n%d:', PixelCounter);
%                     fprintf(SER, '\n%d', PixelData);
%                 end
%             end
            readasync(SER,4);
            
        end

        % Callback function
        function ApplyFiltersButtonPushed(app, event)
            
        end

        % Callback function
        function SetthresholdSliderValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IVPRO = uifigure('Visible', 'off');
            app.IVPRO.Name = 'UI Figure';
            app.IVPRO.KeyPressFcn = createCallbackFcn(app, @SerialTX, true);
            app.TabGroup = uitabgroup(app.IVPRO);
            app.TabGroup.Tooltip = {''};
            app.SerialConfigTab = uitab(app.TabGroup);
            app.SerialConfigTab.Tooltip = {''};
            app.SerialConfigTab.Title = 'Serial Config';
            app.CmdBox = uieditfield(app.SerialConfigTab, 'text');
            app.CmdBox.ValueChangedFcn = createCallbackFcn(app, @CmdBoxValueChanged, true);
            app.CmdBoxLabel = uilabel(app.SerialConfigTab);
            app.CmdBoxLabel.Text = 'Type commands and press enter to send';
            app.HistoryBox = uitextarea(app.SerialConfigTab);
            app.HistoryBoxLabel = uilabel(app.SerialConfigTab);
            app.HistoryBoxLabel.Text = 'Serial TX History';
            app.Panel_2 = uipanel(app.SerialConfigTab);
            app.DisconnectButton = uibutton(app.Panel_2, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectButton = uibutton(app.Panel_2, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.RefreshButton = uibutton(app.Panel_2, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = 'icons8-synchronize-filled-24.png';
            app.RefreshButton.Text = '';
            app.SelectPortDropDownLabel = uilabel(app.Panel_2);
            app.SelectPortDropDownLabel.Text = 'Select Port';
            app.SelectPortDropDown = uidropdown(app.Panel_2);
            app.SelectPortDropDown.Items = {'NO PORTS'};
            app.SelectPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectPortDropDownValueChanged, true);
            app.SelectPortDropDown.Value = 'NO PORTS';
            app.PreStatusLabel = uilabel(app.Panel_2);
            app.PreStatusLabel.Text = 'Connection status:';
            app.StatusLabel = uilabel(app.Panel_2);
            app.StatusLabel.Text = 'Connected';
            app.RXHistory = uitextarea(app.SerialConfigTab);
            app.RXLabel = uilabel(app.SerialConfigTab);
            app.RXLabel.Text = 'Serial RX History';
            app.FilterConfigTab = uitab(app.TabGroup);
            app.FilterConfigTab.Title = 'Filter Config';
            app.SelectImageButton = uibutton(app.FilterConfigTab, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Please select an image...';
            app.FilterControlPanel = uibuttongroup(app.FilterConfigTab);
            app.FilterControlPanel.Title = 'Filter Control Panel';
            app.DenoiseMeanButton = uiradiobutton(app.FilterControlPanel);
            app.DenoiseMeanButton.Text = 'Denoise (Mean)';
            app.DenoiseMeanButton.Value = true;
            app.SharpenButton = uiradiobutton(app.FilterControlPanel);
            app.SharpenButton.Text = 'Sharpen';
            app.DenoiseGaussianButton = uiradiobutton(app.FilterControlPanel);
            app.DenoiseGaussianButton.Text = 'Denoise (Gaussian)';
            app.EdgeDetectionXButton = uiradiobutton(app.FilterControlPanel);
            app.EdgeDetectionXButton.Text = 'Edge Detection (X)';
            app.StretchContrastButton = uiradiobutton(app.FilterControlPanel);
            app.StretchContrastButton.Text = 'Stretch Contrast';
            app.ThresholdButton = uiradiobutton(app.FilterControlPanel);
            app.ThresholdButton.Text = 'Threshold';
            app.EdgeDetectionYButton = uiradiobutton(app.FilterControlPanel);
            app.EdgeDetectionYButton.Text = 'Edge Detection (Y)';
            app.DenoiseMedianButton = uiradiobutton(app.FilterControlPanel);
            app.DenoiseMedianButton.Text = 'Denoise (Median)';
            app.ThresholdSpinnerLabel = uilabel(app.FilterControlPanel);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.FilterControlPanel);
            app.ThresholdSpinner.Limits = [0 255];
            app.ThresholdSpinner.RoundFractionalValues = 'on';
            app.InputImageLabel = uilabel(app.FilterConfigTab);
            app.InputImageLabel.Text = 'Input Image';
            app.Panel = uipanel(app.FilterConfigTab);
            app.Panel.Title = '  ';
            app.RunfilterButton = uibutton(app.Panel, 'push');
            app.RunfilterButton.ButtonPushedFcn = createCallbackFcn(app, @RunfilterButtonPushed, true);
            app.RunfilterButton.Text = 'Run filter';
            app.Label = uilabel(app.FilterConfigTab);
            app.InputImagePanel = uipanel(app.IVPRO);
            app.InputImagePanel.Title = 'Input Image';
            app.InputImagebox = uiaxes(app.InputImagePanel);
            title(app.InputImagebox, '')
            xlabel(app.InputImagebox, '')
            ylabel(app.InputImagebox, '')
            app.InputHistogram = uiaxes(app.InputImagePanel);
            title(app.InputHistogram, 'Luminance Histogram')
            xlabel(app.InputHistogram, 'Pixel Value')
            ylabel(app.InputHistogram, 'Pixel Count')
            app.OutputImagePanel = uipanel(app.IVPRO);
            app.OutputImagePanel.Title = 'Output Image';
            app.OutputImagebox = uiaxes(app.OutputImagePanel);
            title(app.OutputImagebox, '')
            xlabel(app.OutputImagebox, '')
            ylabel(app.OutputImagebox, '')
            app.OutputHistogram = uiaxes(app.OutputImagePanel);
            title(app.OutputHistogram, 'Luminance Histogram')
            xlabel(app.OutputHistogram, 'Pixel Value')
            ylabel(app.OutputHistogram, 'Pixel Count')
            % Show the figure after all components are created
            app.IVPRO.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IVPRO_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IVPRO)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IVPRO)
        end
    end
end